classdef baza_danych < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        EdytujproduktButton             matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        DodajproduktdobazydanychButton  matlab.ui.control.Button
        TluszczeNowegEditField          matlab.ui.control.NumericEditField
        TluszczeNowegEditFieldLabel     matlab.ui.control.Label
        BialkoNowegEditField            matlab.ui.control.NumericEditField
        BialkoNowegEditFieldLabel       matlab.ui.control.Label
        WeglNowegEditField              matlab.ui.control.NumericEditField
        WeglNowegEditFieldLabel         matlab.ui.control.Label
        KalorieNoweEditField            matlab.ui.control.NumericEditField
        KalorieNoweEditFieldLabel       matlab.ui.control.Label
        NazwaNoweEditField              matlab.ui.control.EditField
        NazwaNoweEditFieldLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WpiszNazweProduktuZWielkiejLiteryEditField  matlab.ui.control.EditField
        WpiszNazweProduktuZWielkiejLiteryEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        WczytajbazdanychButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           tabela = readtable('baza_danych_kcal.xlsx') 
        end

        % Button pushed function: WczytajbazdanychButton
        function WczytajbazdanychButtonPushed(app, event)
            %wczytanie pierwszej tabeli
            try
            tabela = readtable('baza_danych_kcal.xlsx')
            global tabela
%             tabela = readtable(file)
            %uialert(app.UIFigure, 'wczytano dane', 'OK', "Icon", 'success')
            catch
            uialert(app.UIFigure, 'Błąd', '!!!', "Icon", 'error')  
            end
            
            % wczytanie tabeli z danymi 
             app.UITable.Enable = 'on';
             app.UITable.Data = tabela
             app.UITable.ColumnName = tabela.Properties.VariableNames;

             
        end

        % Callback function
        function WyszukajproduktButtonPushed(app, event)

        end

        % Value changed function: 
        % WpiszNazweProduktuZWielkiejLiteryEditField
        function WpiszNazweProduktuZWielkiejLiteryEditFieldValueChanged(app, event)
            global tabela
            value = app.WpiszNazweProduktuZWielkiejLiteryEditField.Value;
            if ismember( value, tabela.NazwaProduktu )
                app.Label.Text = 'ok'
            else 
                app.Label.Text = "nie ma takiego produktu"  
                
            end
        end

        % Button pushed function: DodajproduktdobazydanychButton
        function DodajproduktdobazydanychButtonPushed(app, event)
            global tabela 
            global Label  
            global value
            prod = app.NazwaNoweEditField.Value
            kcal = app.KalorieNoweEditField.Value
            wegl = app.WeglNowegEditField.Value
            bial = app.KalorieNoweEditField.Value
            tlu = app.TluszczeNowegEditField.Value
                
            nowe = {prod kcal wegl bial tlu}
            tabela2 = [tabela;nowe]
            app.UITable2.Data = tabela2
            tabela = tabela2

            
        end

        % Button pushed function: EdytujproduktButton
        function EdytujproduktButtonPushed(app, event)
            global tabela 
            global Label  
            global value
            global tabela2
            edytuj = string(app.NazwaNoweEditField.Value)
            for i = 1:length(tabela.NazwaProduktu)
                if string(app.UITable.Data{i, 1}) == edytuj
                    prod2 = app.NazwaNoweEditField.Value
                    kcal2 = app.KalorieNoweEditField.Value
                    wegl2 = app.WeglNowegEditField.Value
                    bial2 = app.KalorieNoweEditField.Value
                    tlu2 = app.TluszczeNowegEditField.Value                
                    app.UITable.Data{i, 2} = kcal2
                    app.UITable.Data{i, 3} = wegl2
                    app.UITable.Data{i, 4} = bial2
                    app.UITable.Data{i, 5} = tlu2
                end
            end        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global tabela 
            global Label  
            global value
            global tabela2
            edytuj = string(app.NazwaNoweEditField.Value)
            numRows = size(app.UITable.Data, 1)
            k =1;
            for i = 1:numRows
                if string(app.UITable.Data{i, 1}) == edytuj
                    app.UITable.Data(i, :) = []
                    k = k + 1;
                end 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WczytajbazdanychButton = uibutton(app.UIFigure, 'push');
            app.WczytajbazdanychButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajbazdanychButtonPushed, true);
            app.WczytajbazdanychButton.Text = {'Wczytaj bazę danych '; ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Produkt'; 'Kalorie'; 'Weglowodany'; 'Bialko'; 'Tluszcz'};
            app.UITable.RowName = {};
            app.WpiszNazweProduktuZWielkiejLiteryEditFieldLabel = uilabel(app.UIFigure);
            app.WpiszNazweProduktuZWielkiejLiteryEditFieldLabel.Text = 'WpiszNazweProduktuZWielkiejLitery';
            app.WpiszNazweProduktuZWielkiejLiteryEditField = uieditfield(app.UIFigure, 'text');
            app.WpiszNazweProduktuZWielkiejLiteryEditField.ValueChangedFcn = createCallbackFcn(app, @WpiszNazweProduktuZWielkiejLiteryEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.NazwaNoweEditFieldLabel = uilabel(app.UIFigure);
            app.NazwaNoweEditFieldLabel.Text = 'NazwaNowe';
            app.NazwaNoweEditField = uieditfield(app.UIFigure, 'text');
            app.KalorieNoweEditFieldLabel = uilabel(app.UIFigure);
            app.KalorieNoweEditFieldLabel.Text = 'KalorieNowe';
            app.KalorieNoweEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeglNowegEditFieldLabel = uilabel(app.UIFigure);
            app.WeglNowegEditFieldLabel.Text = 'WeglNowe [g]';
            app.WeglNowegEditField = uieditfield(app.UIFigure, 'numeric');
            app.BialkoNowegEditFieldLabel = uilabel(app.UIFigure);
            app.BialkoNowegEditFieldLabel.Text = 'BialkoNowe [g]';
            app.BialkoNowegEditField = uieditfield(app.UIFigure, 'numeric');
            app.TluszczeNowegEditFieldLabel = uilabel(app.UIFigure);
            app.TluszczeNowegEditFieldLabel.Text = 'TluszczeNowe [g]';
            app.TluszczeNowegEditField = uieditfield(app.UIFigure, 'numeric');
            app.DodajproduktdobazydanychButton = uibutton(app.UIFigure, 'push');
            app.DodajproduktdobazydanychButton.ButtonPushedFcn = createCallbackFcn(app, @DodajproduktdobazydanychButtonPushed, true);
            app.DodajproduktdobazydanychButton.Text = 'Dodaj produkt do bazy danych ';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.EdytujproduktButton = uibutton(app.UIFigure, 'push');
            app.EdytujproduktButton.ButtonPushedFcn = createCallbackFcn(app, @EdytujproduktButtonPushed, true);
            app.EdytujproduktButton.Text = 'Edytuj produkt';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'kosz na smieci.jpg';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = baza_danych

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end