classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ZdrowieTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        MojedaneTab                     matlab.ui.container.Tab
        PeButtonGroup                   matlab.ui.container.ButtonGroup
        MczyznaButton                   matlab.ui.control.RadioButton
        KobietyButton                   matlab.ui.control.RadioButton
        AktywnoButtonGroup              matlab.ui.container.ButtonGroup
        ZnikomaButton                   matlab.ui.control.RadioButton
        MaaButton                       matlab.ui.control.RadioButton
        UmiarkowanaButton               matlab.ui.control.RadioButton
        DuaButton                       matlab.ui.control.RadioButton
        BardzoduaButton                 matlab.ui.control.RadioButton
        PodajswjwiekEditFieldLabel      matlab.ui.control.Label
        PodajswjwiekEditField           matlab.ui.control.NumericEditField
        PodajswojwagkgLabel             matlab.ui.control.Label
        PodajswojwagkgEditField         matlab.ui.control.NumericEditField
        PodajswjwzrostcmEditFieldLabel  matlab.ui.control.Label
        PodajswjwzrostcmEditField       matlab.ui.control.NumericEditField
        PodajswjobwdbiodercmEditFieldLabel  matlab.ui.control.Label
        PodajswjobwdbiodercmEditField   matlab.ui.control.NumericEditField
        PodajswjobwdwpasiecmEditFieldLabel  matlab.ui.control.Label
        PodajswjobwdwpasiecmEditField   matlab.ui.control.NumericEditField
        KalkulatorkaloriiTab            matlab.ui.container.Tab
        TwojezapotrzebowaniekalorycznewynosiEditFieldLabel  matlab.ui.control.Label
        TwojezapotrzebowaniekalorycznewynosiEditField  matlab.ui.control.EditField
        TwoimcelemjestabyButtonGroup    matlab.ui.container.ButtonGroup
        UtrzymawagButton                matlab.ui.control.RadioButton
        SchudnButton                    matlab.ui.control.RadioButton
        PrzytyButton                    matlab.ui.control.RadioButton
        ObliczswojezapotrzebowaniekaloryczneButton  matlab.ui.control.Button
        RodzajaktywnocifizycznejButtonGroup  matlab.ui.container.ButtonGroup
        TrenujnasiowniuporawiamsportysioweButton  matlab.ui.control.RadioButton
        TrenujsportywytrzymaociowenpbieganieButton  matlab.ui.control.RadioButton
        TrenujodczasudoczasurnedyscyplinysportoweButton  matlab.ui.control.RadioButton
        TextArea_8                      matlab.ui.control.TextArea
        BIAKAEditFieldLabel             matlab.ui.control.Label
        BIAKAEditField                  matlab.ui.control.EditField
        PowinienespoywanastpujciloLabel  matlab.ui.control.Label
        WGLOWODANWEditFieldLabel        matlab.ui.control.Label
        WGLOWODANWEditField             matlab.ui.control.EditField
        TUSZCZUEditFieldLabel           matlab.ui.control.Label
        TUSZCZUEditField                matlab.ui.control.EditField
        TextArea_9                      matlab.ui.control.TextArea
        ObliczzapotrzebowanienamakroskadnikiButton  matlab.ui.control.Button
        KalkulatordietetycznyTab        matlab.ui.container.Tab
        BMIEditFieldLabel               matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        ObliczWspczynnikiButton         matlab.ui.control.Button
        WHREditFieldLabel               matlab.ui.control.Label
        WHREditField                    matlab.ui.control.NumericEditField
        OcenaLabel                      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        WHtREditFieldLabel              matlab.ui.control.Label
        WHtREditField                   matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        OcenaLabel_2                    matlab.ui.control.Label
        WzrBrocaEditFieldLabel          matlab.ui.control.Label
        WzrBrocaEditField               matlab.ui.control.NumericEditField
        TextArea_4                      matlab.ui.control.TextArea
        WzrBrocaBrugshaEditFieldLabel   matlab.ui.control.Label
        WzrBrocaBrugshaEditField        matlab.ui.control.NumericEditField
        TextArea_5                      matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        PPMwzrMillfinaEditFieldLabel    matlab.ui.control.Label
        PPMwzrMillfinaEditField         matlab.ui.control.NumericEditField
        TextArea_6                      matlab.ui.control.TextArea
        YMCAEditFieldLabel              matlab.ui.control.Label
        YMCAEditField                   matlab.ui.control.NumericEditField
        TextArea_7                      matlab.ui.control.TextArea
        WartodywczeTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        WizualizacjaTab                 matlab.ui.container.Tab
        ZapotrzebowaniekalorieEditFieldLabel  matlab.ui.control.Label
        ZapotrzebowaniekalorieEditField  matlab.ui.control.EditField
        ZjedzonekalorieEditFieldLabel   matlab.ui.control.Label
        ZjedzonekalorieEditField        matlab.ui.control.EditField
        ZapotrzebowaniebiakoEditFieldLabel  matlab.ui.control.Label
        ZapotrzebowaniebiakoEditField   matlab.ui.control.EditField
        ZjedzonebiakoEditFieldLabel     matlab.ui.control.Label
        ZjedzonebiakoEditField          matlab.ui.control.EditField
        ZapotrzebowanietuszczeEditFieldLabel  matlab.ui.control.Label
        ZapotrzebowanietuszczeEditField  matlab.ui.control.EditField
        ZjedzonetuszczeEditFieldLabel   matlab.ui.control.Label
        ZjedzonetuszczeEditField        matlab.ui.control.EditField
        ZapotrzebowaniewglowodanyEditFieldLabel  matlab.ui.control.Label
        ZapotrzebowaniewglowodanyEditField  matlab.ui.control.EditField
        ZjedzonewglowodanyEditFieldLabel  matlab.ui.control.Label
        ZjedzonewglowodanyEditField     matlab.ui.control.EditField
        UITable4                        matlab.ui.control.Table
        dodajproduktButton              matlab.ui.control.Button
        wyczytabeleButton               matlab.ui.control.Button
        NazwaproduktuEditFieldLabel     matlab.ui.control.Label
        NazwaproduktuEditField          matlab.ui.control.EditField
        KalorieEditFieldLabel           matlab.ui.control.Label
        KalorieEditField                matlab.ui.control.NumericEditField
        WeglowodanyEditFieldLabel       matlab.ui.control.Label
        WeglowodanyEditField            matlab.ui.control.NumericEditField
        BialkoEditFieldLabel            matlab.ui.control.Label
        BialkoEditField                 matlab.ui.control.NumericEditField
        TluszczeEditFieldLabel          matlab.ui.control.Label
        TluszczeEditField               matlab.ui.control.NumericEditField
        EdytujbazdanychTab              matlab.ui.container.Tab
        WczytajbazdanychButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        DodajproduktdobazydanychButton  matlab.ui.control.Button
        EdytujproduktButton             matlab.ui.control.Button
        UsuproduktButton                matlab.ui.control.Button
        WpiszNazweProduktuZWielkiejLiteryEditFieldLabel  matlab.ui.control.Label
        WpiszNazweProduktuZWielkiejLiteryEditField  matlab.ui.control.EditField
        NazwaNoweEditFieldLabel         matlab.ui.control.Label
        NazwaNoweEditField              matlab.ui.control.EditField
        KalorieNoweEditFieldLabel       matlab.ui.control.Label
        KalorieNoweEditField            matlab.ui.control.NumericEditField
        WeglNowegEditFieldLabel         matlab.ui.control.Label
        WeglNowegEditField              matlab.ui.control.NumericEditField
        BialkoNowegEditFieldLabel       matlab.ui.control.Label
        BialkoNowegEditField            matlab.ui.control.NumericEditField
        TluszczeNowegEditFieldLabel     matlab.ui.control.Label
        TluszczeNowegEditField          matlab.ui.control.NumericEditField
        ZapiszzmianyButton              matlab.ui.control.Button
        SportTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        TrasaTab                        matlab.ui.container.Tab
        RodzajaktywnociButtonGroup      matlab.ui.container.ButtonGroup
        BieganieButton                  matlab.ui.control.RadioButton
        RowerButton                     matlab.ui.control.RadioButton
        WybierzwojewdztwoButtonGroup    matlab.ui.container.ButtonGroup
        dolnolskieButton                matlab.ui.control.RadioButton
        kujawskopomorskieButton         matlab.ui.control.RadioButton
        lubelskieButton                 matlab.ui.control.RadioButton
        lubuskieButton                  matlab.ui.control.RadioButton
        dzkieButton                     matlab.ui.control.RadioButton
        maopolskieButton                matlab.ui.control.RadioButton
        mazowieckieButton               matlab.ui.control.RadioButton
        opolskieButton                  matlab.ui.control.RadioButton
        podkarpackieButton              matlab.ui.control.RadioButton
        podlaskieButton                 matlab.ui.control.RadioButton
        pomorskieButton                 matlab.ui.control.RadioButton
        lskieButton                     matlab.ui.control.RadioButton
        witokrzyskieButton              matlab.ui.control.RadioButton
        warmiskomazurskieButton         matlab.ui.control.RadioButton
        wielkopolskieButton             matlab.ui.control.RadioButton
        zachodniopomorskieButton        matlab.ui.control.RadioButton
        DugotrasywkmLabel               matlab.ui.control.Label
        SzukajButton                    matlab.ui.control.Button
        PokatrasButton                  matlab.ui.control.StateButton
        PokaprofilterenuButton          matlab.ui.control.StateButton
        DodanienowejtrasydobazydanychLabel  matlab.ui.control.Label
        DodajtrasButton                 matlab.ui.control.Button
        MAXEditFieldLabel               matlab.ui.control.Label
        MAXEditField                    matlab.ui.control.NumericEditField
        MINEditFieldLabel               matlab.ui.control.Label
        MINEditField                    matlab.ui.control.NumericEditField
        TrasydowyboruListBoxLabel       matlab.ui.control.Label
        TrasydowyboruListBox            matlab.ui.control.ListBox
        WybierzwojewdztwodlanowejtrasyDropDownLabel  matlab.ui.control.Label
        WybierzwojewdztwodlanowejtrasyDropDown  matlab.ui.control.DropDown
        WybierzrodzajaktywnocidlanowejtrasyDropDownLabel  matlab.ui.control.Label
        WybierzrodzajaktywnocidlanowejtrasyDropDown  matlab.ui.control.DropDown
        TemperaturaLabel                matlab.ui.control.Label
        CinienieLabel                   matlab.ui.control.Label
        IndeksUVLabel                   matlab.ui.control.Label
        WiatrLabel                      matlab.ui.control.Label
        WywietlwarunkipogodowedlawybranejtrasyButton  matlab.ui.control.Button
        cisnienie_hpa                   matlab.ui.control.EditField
        temperatura_celsjusz            matlab.ui.control.EditField
        wiatr_predkosc                  matlab.ui.control.EditField
        indeks_uv                       matlab.ui.control.EditField
        UsutrasButton                   matlab.ui.control.Button
        StatystykiTab                   matlab.ui.container.Tab
        SpalonekalorieLabel             matlab.ui.control.Label
        spalone_kalorie                 matlab.ui.control.NumericEditField
        redniaprdkokmhLabel             matlab.ui.control.Label
        rednietempominkmLabel           matlab.ui.control.Label
        srednia_predkosc                matlab.ui.control.NumericEditField
        srednie_tempo                   matlab.ui.control.NumericEditField
        CzaswiczeminLabel               matlab.ui.control.Label
        czas_cwiczen                    matlab.ui.control.NumericEditField
        SumarycznadugotrasypodgrkmLabel  matlab.ui.control.Label
        trasa_w_gore                    matlab.ui.control.NumericEditField
        trasa_w_dol                     matlab.ui.control.NumericEditField
        SumarycznadugotrasywdkmLabel    matlab.ui.control.Label
        ObliczstatystkinawybranejtrasieButton  matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        PokawykresButton                matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        ZapisztreningButton             matlab.ui.control.Button
        UITable3_2                      matlab.ui.control.Table
        PokarezultatButton              matlab.ui.control.Button
        WczytajpoprzednietreningiButton  matlab.ui.control.Button
        TextArea_10                     matlab.ui.control.TextArea
        DzieEditFieldLabel              matlab.ui.control.Label
        DzieEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        skrot = 'gpx'
        system_odniesienia = wgs84Ellipsoid;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             wszystkie_trasy = dir("wszystkie_trasy");
             cd wszystkie_trasy;
             
             dystans = [];
             nazwy_tras = [];
             pobrane_trasy = {};
             for i = 3:length(wszystkie_trasy);
                 n_t = convertCharsToStrings(wszystkie_trasy(i).name);
                 nazwy_tras = [nazwy_tras
                               n_t];
                 
                 pob_t = gpxread(sprintf(n_t), 'FeatureType', 'track');
                 pobrane_trasy{i-2} = pob_t;
                 
                 lat = pob_t.Latitude;
                 lon = pob_t.Longitude;
                 
                 d_miedzy_pkt = distance(lat(1:end-1), lon(1:end-1), lat(2:end), lon(2:end), app.system_odniesienia);
                 d = sum(d_miedzy_pkt)./1000;
                 dystans = [dystans
                            d];
             end
             
             pobrane_trasy = pobrane_trasy';
             
             global dystans
             global nazwy_tras
             global pobrane_trasy
             
             cd ..\
        end

        % Button pushed function: SzukajButton
        function SzukajButtonPushed(app, event)
            global dystans
            global nazwy_tras
            
            
            max_d = app.MAXEditField.Value;
            min_d = app.MINEditField.Value;
            
            
            cd trasy_po_woj;
            
            rodz_akt = app.RodzajaktywnociButtonGroup.SelectedObject;
            rodzaj_aktywnosci = rodz_akt.Text;
            cd(sprintf(rodzaj_aktywnosci));
            
            miej_akt = app.WybierzwojewdztwoButtonGroup.SelectedObject;
            miejsce_aktywnosci = miej_akt.Text;
            cd(sprintf(miejsce_aktywnosci));
            
            l_na = dir('*.gpx');
            Lista_nazw = {};
            kolejne_pkt = 1;
            for i = 1:length(l_na);
                lista_nazw = l_na(i).name;
                indeks_nazw = find(nazwy_tras==lista_nazw);
                lista_nazw = lista_nazw(1:length(lista_nazw)-4);
                lista_nazw = convertStringsToChars(sprintf("%s - %d km",convertCharsToStrings(lista_nazw),round(dystans(indeks_nazw))));
                if max_d~=0 & min_d~=0;
                   if round(dystans(indeks_nazw))<max_d & round(dystans(indeks_nazw))>min_d;
                      Lista_nazw{kolejne_pkt} = lista_nazw;
                      kolejne_pkt = kolejne_pkt + 1;
                   end
                else
                      Lista_nazw{i} = lista_nazw;
                end
            end
            
            app.TrasydowyboruListBox.Items = Lista_nazw;
            
            cd ..\..\..;
            global indeks_nazw
        end

        % Value changed function: PokatrasButton
        function PokatrasButtonValueChanged(app, event)
            global pobrane_trasy
            global nazwy_tras
            
            wybrana_trasa = app.TrasydowyboruListBox.Value;
            wybrana_trasa = split(wybrana_trasa," -");
            wybrana_trasa = sprintf("%s.%s",wybrana_trasa{1},app.skrot);
            
            indeks_wybranej_trasy = find(nazwy_tras==wybrana_trasa);
            dane_wybranej_trasy = pobrane_trasy{indeks_wybranej_trasy};
            
            webmap('openstreetmap');
            wmline(dane_wybranej_trasy,"Color","magenta");
            
        end

        % Value changed function: PokaprofilterenuButton
        function PokaprofilterenuButtonValueChanged(app, event)
            global pobrane_trasy
            global nazwy_tras
            
            
            wybrana_trasa = app.TrasydowyboruListBox.Value;
            wybrana_trasa = split(wybrana_trasa," -");
            wybrana_trasa = sprintf("%s.%s",wybrana_trasa{1},app.skrot);
            
            indeks_wybranej_trasy = find(nazwy_tras==wybrana_trasa);
            dane_wybranej_trasy = pobrane_trasy{indeks_wybranej_trasy};
            
            lat_wybranej_trasy = dane_wybranej_trasy.Latitude;
            lon_wybranej_trasy = dane_wybranej_trasy.Longitude;
            
            dlugosc_wybranej_trasy = distance(lat_wybranej_trasy(1:end-1), lon_wybranej_trasy(1:end-1), lat_wybranej_trasy(2:end), lon_wybranej_trasy(2:end), app.system_odniesienia);
            
            dlugosc_trasy_pkt = [0];
            dlugosc_pkt = 0;
            for i = 1:size(dlugosc_wybranej_trasy,2);
                dlugosc_pkt = dlugosc_pkt + dlugosc_wybranej_trasy(i);
                dlugosc_trasy_pkt = [dlugosc_trasy_pkt dlugosc_pkt];
            end
            
            figure 
            plot(dlugosc_trasy_pkt,dane_wybranej_trasy.Elevation);
            title('Profil terenu');
            ylabel('wysokość n.p.m.');
            xlabel('długość trasy');
            xlim([0 dlugosc_pkt]);
        end

        % Button pushed function: DodajtrasButton
        function DodajtrasButtonPushed(app, event)
            wybrana_aktywnosc = convertCharsToStrings(app.WybierzrodzajaktywnocidlanowejtrasyDropDown.Value);
            wybrane_wojewodztwo = convertCharsToStrings(app.WybierzwojewdztwodlanowejtrasyDropDown.Value);
            
            try
                [file,path,indx]=uigetfile('*.gpx','Wybierz plik do dodania.');
                assert(indx==1,'Złe rozszerzenie pliku')
                uialert(app.UIFigure,'Plik został dodany poprawnie', 'Akceptacja',"Icon","success");
                sciezka_dostepu = sprintf("%s\%s",path,file); 
            
                przeniesienie_do_folderu_wszystkie = movefile(sciezka_dostepu,"wszystkie_trasy");
            
                skopiowanie_do_konkretnego_folderu = copyfile(sprintf("wszystkie_trasy\\%s",file),sprintf("trasy_po_woj\\%s\\%s",...
                    wybrana_aktywnosc,wybrane_wojewodztwo));
            catch 
                uialert(app.UIFigure,'Złe rozszerzenie pliku. Aplikacja dodaje tylko pliki .gpx', 'Błąd',"Icon","error")
            end
        end

        % Value changed function: PodajswjwiekEditField
        function PodajswjwiekEditFieldValueChanged(app, event)
            value = app.PodajswjwiekEditField.Value;
            app.PodajswjwiekEditField.Limits = [0, 100];
            app.PodajswjwiekEditField.LowerLimitInclusive = 'off';
            app.PodajswjwiekEditField.UpperLimitInclusive = 'on';
            if app.PodajswjwiekEditField.Value < 0
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            elseif app.PodajswjwiekEditField.Value >= 100
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            else
                uialert(app.UIFigure, 'Wartość mieści się w limicie', 'Dobrze wprowadzone dane', "Icon", "success");
            end
            WIEK = value
            
            global WIEK
        end

        % Value changed function: PodajswojwagkgEditField
        function PodajswojwagkgEditFieldValueChanged(app, event)
            value = app.PodajswojwagkgEditField.Value;
            app.PodajswojwagkgEditField.Limits = [0, 300];
            app.PodajswojwagkgEditField.LowerLimitInclusive = 'off';
            app.PodajswojwagkgEditField.UpperLimitInclusive = 'on';
            if app.PodajswojwagkgEditField.Value < 0
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            elseif app.PodajswojwagkgEditField.Value >= 300
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            else
                uialert(app.UIFigure, 'Wartość mieści się w limicie', 'Dobrze wprowadzone dane' , "Icon", "success");
            end
            WAGA = value;
            
            global WAGA
            
        end

        % Value changed function: PodajswjwzrostcmEditField
        function PodajswjwzrostcmEditFieldValueChanged(app, event)
            value = app.PodajswjwzrostcmEditField.Value;
            app.PodajswjwzrostcmEditField.Limits = [100, 250];
            app.PodajswjwzrostcmEditField.LowerLimitInclusive = 'off';
            app.PodajswjwzrostcmEditField.UpperLimitInclusive = 'on';
            if app.PodajswjwzrostcmEditField.Value < 100
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            elseif app.PodajswjwzrostcmEditField.Value >= 250
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            else
                uialert(app.UIFigure, 'Wartość mieści się w limicie', 'Dobrze wprowadzone dane' , "Icon", "success");
            end
            WZROST = value;
            
            global WZROST
        end

        % Button pushed function: ObliczWspczynnikiButton
        function ObliczWspczynnikiButtonPushed(app, event)
            WAGA = app.PodajswojwagkgEditField.Value
            WZROST = app.PodajswjwzrostcmEditField.Value
            WIEK = app.PodajswjwiekEditField.Value
            obwod_bioder = app.PodajswjobwdbiodercmEditField.Value
            obwod_pas = app.PodajswjobwdwpasiecmEditField.Value
   
            BMI = WAGA/(WZROST/100)^2;
            app.BMIEditField.Value = BMI;
           
            %WHR
            app.WHREditField.Value = obwod_pas/obwod_bioder;
            if app.MczyznaButton.Value == 1
                if app.WHREditField.Value <= 1
                    app.OcenaLabel.Text = 'wartość prawidłowa'
                else
                    app.OcenaLabel.Text = 'wartość nie jest prawidłowa'   
                end
            end
            if app.KobietyButton.Value == 1
                if app.WHREditField.Value <= 0.8
                     app.OcenaLabel.Text = 'wartość prawidłowa'
                else
                    app.OcenaLabel.Text = 'wartość nie jest prawidłowa'
                end
            end
            
            %WHTR
            WHTR = (obwod_pas/ WZROST)/100
            app.WHtREditField.Value = WHTR
            if app.MczyznaButton.Value == 1
                if WHTR<35
                    app.OcenaLabel_2.Text = 'niedożywienie'
                elseif (35<WHTR)&&(WHTR<43)
                    app.OcenaLabel_2.Text = 'niedowaga'
                elseif (43<WHTR)&&(WHTR<46)
                    app.OcenaLabel_2.Text = 'Lekka niedowaga'
                elseif (46<WHTR)&&(WHTR<53)
                    app.OcenaLabel_2.Text = 'Prawidłowa masa ciała'
                elseif (53<WHTR)&&(WHTR<63)
                    app.OcenaLabel_2.Text = 'Poważna nadwaga'
                else 
                    app.OcenaLabel_2.Text = 'otyłość'
                    
                end
            end
            if app.KobietyButton.Value == 1
                if WHTR<35
                    app.OcenaLabel_2.Text = 'niedożywienie'
                elseif (35<WHTR)&&(WHTR<42)
                    app.OcenaLabel_2.Text = 'niedowaga'
                elseif (42<WHTR)&&(WHTR<46)
                    app.OcenaLabel_2.Text = 'Lekka niedowaga'
                elseif (46<WHTR)&&(WHTR<49)
                    app.OcenaLabel_2.Text = 'Prawidłowa masa ciała'
                elseif (49<WHTR)&&(WHTR<58)
                    app.OcenaLabel_2.Text = 'Poważna nadwaga'
                else 
                    app.OcenaLabel_2.Text = 'otyłość'
                    
                end
            end
            %BROCA
            if app.MczyznaButton.Value == 1
                app.WzrBrocaEditField.Value = (WZROST-100)*0.9
            elseif app.KobietyButton.Value ==1 
                app.WzrBrocaEditField.Value = (WZROST-100)*0.85 
            end
            
            %BROCA-BRUGSHA
            if (155<WZROST) && (WZROST<164)
                app.WzrBrocaBrugshaEditField.Value = WZROST-100
            elseif (165<WZROST) && (WZROST<175)
                app.WzrBrocaBrugshaEditField.Value = WZROST-105
            elseif (176<WZROST) && (WZROST<190)
                app.WzrBrocaBrugshaEditField.Value = WZROST-110
            elseif WZROST>190
                app.WzrBrocaBrugshaEditField.Value = 0
                app.Label_2.Text = "jesteś za wysoki do obliczenia tego wskaźnika"
            end
            
            %PPM
            if app.MczyznaButton.Value == 1
                app.PPMwzrMillfinaEditField.Value = (10*WAGA)+(6.25*WZROST)-(5*WIEK) + 5  
            elseif app.KobietyButton.Value == 1
                app.PPMwzrMillfinaEditField.Value = (10*WAGA)+(6.25*WZROST)-(5*WIEK) - 161
            end
            
            %YMCA
            if app.KobietyButton.Value == 1
                app.YMCAEditField.Value = (((1.6348*obwod_pas)-(0.1804* WAGA -76.76))/(2.2*WAGA))*100
            end
            if app.MczyznaButton.Value == 1
                app.YMCAEditField.Value = (((1.6348*obwod_pas)-(0.1804*WAGA -98.42))/(2.2*WAGA))*100
            end
           
        end

        % Button pushed function: 
        % ObliczswojezapotrzebowaniekaloryczneButton
        function ObliczswojezapotrzebowaniekaloryczneButtonPushed2(app, event)
            %alert
            if app.PodajswojwagkgEditField.Value == 0 || ...
                    app.PodajswjwiekEditField.Value == 0 || ...
                    app.PodajswjwzrostcmEditField.Value == 0
                uialert(app.UIFigure, 'Proszę wprowadzić wagę, wzrost i wiek w Zdrowie/Moje dane', ...
                    'Błąd', "Icon", "error");
            end
            
            if app.MczyznaButton.Value == 1
                BMR = 66 + (13.7 * app.PodajswojwagkgEditField.Value) ...
                    + (5 * app.PodajswjwzrostcmEditField.Value) - ... 
                     (6.8 * app.PodajswjwiekEditField.Value);
            elseif app.KobietyButton.Value == 1
                BMR = 655 + (9.6 * app.PodajswojwagkgEditField.Value) ...
                    + (1.8 * app.PodajswjwzrostcmEditField.Value) - ...
                    (4.7 * app.PodajswjwiekEditField.Value);
            end
            
            if app.ZnikomaButton.Value == 1
                CPM = BMR * 1.2
            elseif app.MaaButton.Value == 1
                CPM = BMR * 1.375
            elseif app.UmiarkowanaButton.Value == 1
                CPM = BMR * 1.55
            elseif app.DuaButton.Value == 1
                CPM = BMR * 1.725
            elseif app.BardzoduaButton.Value == 1
                CPM = BMR * 1.9 
            end
            
            if app.UtrzymawagButton.Value == 1
                zapotrzebowanie = CPM
            elseif app.SchudnButton.Value == 1
                zapotrzebowanie = CPM - 300
            elseif app.PrzytyButton.Value == 1
                zapotrzebowanie = CPM + 300
            end
            
            wynik_zapotrzebowanie = [num2str(round(zapotrzebowanie)), ' kcal']
            
            app.TwojezapotrzebowaniekalorycznewynosiEditField.Value = num2str(wynik_zapotrzebowanie)
            app.ZapotrzebowaniekalorieEditField.Value = num2str(wynik_zapotrzebowanie)
            
            app.ZapotrzebowaniebiakoEditField.Value = [num2str(round(zapotrzebowanie*0.15/4)), 'g']
            
            app.ZapotrzebowaniewglowodanyEditField.Value = [num2str(round(zapotrzebowanie*0.55/4)), 'g']
            
            app.ZapotrzebowanietuszczeEditField.Value = [num2str(round(zapotrzebowanie*0.30/9)), 'g']
        end

        % Button pushed function: 
        % ObliczstatystkinawybranejtrasieButton
        function ObliczstatystkinawybranejtrasieButtonPushed(app, event)
            global nazwy_tras pobrane_trasy           
            % !ALERT!
            if app.czas_cwiczen.Value <= 0 || ...
                    app.PodajswojwagkgEditField.Value == 0 || ...
                    app.PodajswjwiekEditField.Value == 0 || ...
                    app.PodajswjwzrostcmEditField.Value == 0
                uialert(app.UIFigure, 'Proszę wprowadzić wagę, wzrost i wiek w Zdrowie/Moje dane oraz czas', ...
                    'Błąd', "Icon", "error");
            end
            
            % DANE
            czas_minuty = app.czas_cwiczen.Value;
            czas_godziny = czas_minuty / 60;
            
            wybrana_trasa = app.TrasydowyboruListBox.Value;
            wybrana_trasa = split(wybrana_trasa," -");
            wybrana_trasa = sprintf("%s.%s",wybrana_trasa{1},app.skrot);
            
            indeks_wybranej_trasy = find(nazwy_tras == wybrana_trasa);
            dane_wybranej_trasy = pobrane_trasy{indeks_wybranej_trasy};
            
            lat_stat = dane_wybranej_trasy.Latitude;
            lon_stat = dane_wybranej_trasy.Longitude;
            elev_stat = dane_wybranej_trasy.Elevation;            
            
            a = app.system_odniesienia.SemimajorAxis;
            e2 = app.system_odniesienia.Eccentricity ^ 2;
            
            if app.MczyznaButton.Value == 1
                BMR_sport = 66 + (13.7 * app.PodajswojwagkgEditField.Value) ...
                    + (5 * app.PodajswjwzrostcmEditField.Value) - ... 
                     (6.8 * app.PodajswjwiekEditField.Value);
            elseif app.KobietyButton.Value == 1
                BMR_sport = 655 + (9.6 * app.PodajswojwagkgEditField.Value) ...
                    + (1.8 * app.PodajswjwzrostcmEditField.Value) - ...
                    (4.7 * app.PodajswjwiekEditField.Value);
            end
            
            % ODLEGLOSC
            indeksy_dla_zer = find(elev_stat == 0);
            lat_stat(indeksy_dla_zer) = [];
            lon_stat(indeksy_dla_zer) = [];
            elev_stat(indeksy_dla_zer) = [];
            
            fi_s = deg2rad((lat_stat(1,1:size(lat_stat,2) - 1) + ...
                lat_stat(1,2:size(lat_stat,2))) / 2);
            
            ha = elev_stat(1, 1:size(elev_stat,2) - 1);
            hb = elev_stat(1, 2:size(elev_stat,2));
            hab = hb - ha;
            
            
            Ms = (a .*(1 - e2)) ./ sqrt((1 - e2 .* sin(fi_s).^2).^3);
            Ns = a ./ sqrt(1 - e2 .* sin(fi_s).^2);
            R = sqrt(Ns .* Ms);  
            
            cieciwa = distance('rh', lat_stat(1:end-1), lon_stat(1:end-1), ...
                lat_stat(2:end), lon_stat(2:end), app.system_odniesienia);
            s = sqrt(cieciwa .^2 .* (1 + ha ./ R) .* (1 + hb ./ R) + hab .^ 2);
                        
            dlugosc_trasy_km = sum(s) / 1000
            
            
            % LICZENIE SPALONYCH KALORII
            if app.BieganieButton.Value == 1
                mets0 = [6, 8.3, 9, 9.8, 10.5, 11, 11.8, 12.3, 12.8, ...
                        14.5, 16, 19, 19.8, 23];
                mph0 = [4, 5, 5.2, 6, 6.7, 7, 8, 8.6, 9, 10, 11, 12, ...
                        13, 14];  
            elseif app.RowerButton.Value == 1
                mets0 = [3.5, 5.8, 6.8, 8, 10, 12, 15.8];
                mph0 = [5.5, 9.4, 11.9, 13.9, 15.9, 19, 20];
            end
            
            srednia_predkosc = dlugosc_trasy_km / czas_godziny;
            srednie_tempo = czas_minuty / dlugosc_trasy_km;


            dlugosc_trasy_mile = dlugosc_trasy_km * 0.62137;
            
            mph = dlugosc_trasy_mile / czas_godziny;
            
            jedynki = mph <= mph0
            for i = 1:size(mph0,2)
                if jedynki(i) == 1
                    mets = mets0(i);
                    break
                end
                if jedynki == 0
                    mets = max(mets0);
                end
            end
            
            kcal_sport = BMR_sport * mets * czas_godziny / 24;
            
            
            suma_dlugosci_trasy_w_gore = 0
            suma_dlugosci_trasy_w_dol = 0
            for i = 1:size(elev_stat,2) - 1
                if hab(i) >= 0
                    suma_dlugosci_trasy_w_gore = suma_dlugosci_trasy_w_gore ...
                        + s(i);
                elseif hab(i) < 0
                    suma_dlugosci_trasy_w_dol = suma_dlugosci_trasy_w_dol ...
                        + s(i);
                end
            end
            
            
            if app.czas_cwiczen.Value > 0 && ...
                    app.PodajswojwagkgEditField.Value ~= 0 && ...
                    app.PodajswjwiekEditField.Value ~= 0 && ...
                    app.PodajswjwzrostcmEditField.Value ~= 0
                
                app.srednia_predkosc.Value = srednia_predkosc;
                app.srednie_tempo.Value = srednie_tempo;
                app.spalone_kalorie.Value = kcal_sport;
                app.trasa_w_dol.Value = suma_dlugosci_trasy_w_dol / 1000;
                app.trasa_w_gore.Value = suma_dlugosci_trasy_w_gore / 1000;
            
            else
                
                app.srednia_predkosc.Value = 0;
                app.srednie_tempo.Value = 0;
                app.spalone_kalorie.Value = 0;
                app.trasa_w_dol.Value = 0;
                app.trasa_w_gore.Value = 0;            
            end
            
            
        end

        % Button pushed function: WczytajbazdanychButton
        function WczytajbazdanychButtonPushed(app, event)
            try
            tabela = readtable('baza_danych_kcal.xlsx')
            
            %uialert(app.UIFigure, 'wczytano dane', 'OK', "Icon", 'success')
            catch
            uialert(app.UIFigure, 'Błąd', '!!!', "Icon", 'error')  
            end
            
            % wczytanie tabeli z danymi 
             app.UITable.Enable = 'on';
             app.UITable.Data = tabela;
             app.UITable.ColumnName = tabela.Properties.VariableNames;            
        end

        % Value changed function: 
        % WpiszNazweProduktuZWielkiejLiteryEditField
        function WpiszNazweProduktuZWielkiejLiteryEditFieldValueChanged(app, event)
            tabela = app.UITable.Data
            value = app.WpiszNazweProduktuZWielkiejLiteryEditField.Value;
            
            if ismember( value, tabela.NazwaProduktu )
                app.Label.Text = 'ok';
            else 
                app.Label.Text = "nie ma takiego produktu"  ; 
            end            
        end

        % Button pushed function: DodajproduktdobazydanychButton
        function DodajproduktdobazydanychButtonPushed(app, event)
            tabela = app.UITable.Data
            value = app.WpiszNazweProduktuZWielkiejLiteryEditField.Value;
            prod = app.NazwaNoweEditField.Value;
            kcal = app.KalorieNoweEditField.Value;
            wegl = app.WeglNowegEditField.Value;
            bial = app.BialkoNowegEditField.Value;
            tlu = app.TluszczeNowegEditField.Value;
                
            nowe = {prod kcal wegl bial tlu};
            tabela = [tabela;nowe];
            app.UITable.Data = tabela;
        end

        % Button pushed function: EdytujproduktButton
        function EdytujproduktButtonPushed(app, event)
            tabela = app.UITable.Data

            value = app.WpiszNazweProduktuZWielkiejLiteryEditField.Value;
            edytuj = string(app.NazwaNoweEditField.Value);
            liczba_wierszy = length(tabela.NazwaProduktu)
            for i = 1:liczba_wierszy;
                if string(app.UITable.Data{i, 1}) == edytuj
                    prod2 = app.NazwaNoweEditField.Value;
                    kcal2 = app.KalorieNoweEditField.Value;
                    wegl2 = app.WeglNowegEditField.Value;
                    bial2 = app.BialkoNowegEditField.Value;
                    tlu2 = app.TluszczeNowegEditField.Value;              
                    app.UITable.Data{i, 2} = kcal2;
                    app.UITable.Data{i, 3} = wegl2;
                    app.UITable.Data{i, 4} = bial2;
                    app.UITable.Data{i, 5} = tlu2;
                end
            end            
        end

        % Button pushed function: UsuproduktButton
        function UsuproduktButtonPushed(app, event)
            tabela = app.UITable.Data
            value = app.WpiszNazweProduktuZWielkiejLiteryEditField.Value;
            
            edytuj = string(app.NazwaNoweEditField.Value);
            numRows = size(app.UITable.Data, 1);
            k =1;
            for i = 1:numRows
                if string(app.UITable.Data{i, 1}) == edytuj
                    app.UITable.Data(i, :) = [];
                    k = k + 1;
                    numRows = numRows - 1;
                end 
            end             
        end

        % Button pushed function: ZapiszzmianyButton
        function ZapiszzmianyButtonPushed(app, event)
            tabela = app.UITable.Data
            try
            writetable(tabela, 'baza_danych_kcal.xlsx');
            
            uialert(app.UIFigure, 'Zapisano zmiany', 'OK', "Icon", 'success')
            catch
            uialert(app.UIFigure, 'Błąd', '!!!', "Icon", 'error')  
            end
        end

        % Value changed function: PodajswjobwdbiodercmEditField
        function PodajswjobwdbiodercmEditFieldValueChanged(app, event)
            value = app.PodajswjobwdbiodercmEditField.Value;
            if app.PodajswjobwdbiodercmEditField.Value <= 0
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            else
                uialert(app.UIFigure, 'Wartość mieści się w limicie', 'Dobrze wprowadzone dane' , "Icon", "success");
            end
            obwod_bioder = value;
            global obwod_bioder
        end

        % Value changed function: PodajswjobwdwpasiecmEditField
        function PodajswjobwdwpasiecmEditFieldValueChanged(app, event)
            value = app.PodajswjobwdwpasiecmEditField.Value;
            if app.PodajswjobwdwpasiecmEditField.Value <= 0
                uialert(app.UIFigure, 'Wartość poza limitem', 'Błąd wprowadzania', "Icon", "error");
            else
                uialert(app.UIFigure, 'Wartość mieści się w limicie', 'Dobrze wprowadzone dane' , "Icon", "success");
            end
            obwod_pas = value;
            global obwod_pas
        end

        % Value changed function: ZapotrzebowaniekalorieEditField
        function ZapotrzebowaniekalorieEditFieldValueChanged(app, event)
            value = app.ZapotrzebowaniekalorieEditField.Value;
            
        end

        % Value changing function: ZapotrzebowaniekalorieEditField
        function ZapotrzebowaniekalorieEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: 
        % ObliczzapotrzebowanienamakroskadnikiButton
        function ObliczzapotrzebowanienamakroskadnikiButtonPushed(app, event)
           if app.PodajswojwagkgEditField.Value == 0 || ...
                    app.PodajswjwiekEditField.Value == 0 || ...
                    app.PodajswjwzrostcmEditField.Value == 0
                uialert(app.UIFigure, 'Proszę wprowadzić wagę, wzrost i wiek w Zdrowie/Moje dane', ...
                    'Błąd', "Icon", "error");
            end
            
            if app.MczyznaButton.Value == 1
                BMR = 66 + (13.7 * app.PodajswojwagkgEditField.Value) ...
                    + (5 * app.PodajswjwzrostcmEditField.Value) - ... 
                     (6.8 * app.PodajswjwiekEditField.Value);
            elseif app.KobietyButton.Value == 1
                BMR = 655 + (9.6 * app.PodajswojwagkgEditField.Value) ...
                    + (1.8 * app.PodajswjwzrostcmEditField.Value) - ...
                    (4.7 * app.PodajswjwiekEditField.Value);
            end
            
            if app.ZnikomaButton.Value == 1
                CPM = BMR * 1.2
            elseif app.MaaButton.Value == 1
                CPM = BMR * 1.375
            elseif app.UmiarkowanaButton.Value == 1
                CPM = BMR * 1.55
            elseif app.DuaButton.Value == 1
                CPM = BMR * 1.725
            elseif app.BardzoduaButton.Value == 1
                CPM = BMR * 1.9 
            end
            
            if app.UtrzymawagButton.Value == 1
                zapotrzebowanie = CPM
            elseif app.SchudnButton.Value == 1
                zapotrzebowanie = CPM - 300
            elseif app.PrzytyButton.Value == 1
                zapotrzebowanie = CPM + 300
            end
           
           if app.TrenujnasiowniuporawiamsportysioweButton.Value == 1
                wegle_kcal = round(zapotrzebowanie * 50/100)
                tluszcze_kcal = round(zapotrzebowanie * 30/100)
                bialko_kcal = round(zapotrzebowanie * 20/100)
            elseif app.TrenujsportywytrzymaociowenpbieganieButton.Value == 1
                wegle_kcal = round(zapotrzebowanie * 60/100)
                tluszcze_kcal = round(zapotrzebowanie * 25/100)
                bialko_kcal = round(zapotrzebowanie * 15/100)
            elseif app.TrenujodczasudoczasurnedyscyplinysportoweButton.Value == 1
                wegle_kcal = round(zapotrzebowanie * 55/100)
                tluszcze_kcal = round(zapotrzebowanie * 30/100)
                bialko_kcal = round(zapotrzebowanie * 15/100)
            end
                
                wegle_gram = round(wegle_kcal/4)
                tluszcze_gram = round(tluszcze_kcal/9)
                bialko_gram = round(bialko_kcal/4)
                
            tluszcze_wynik = [num2str(tluszcze_kcal), 'kcal = ', num2str(tluszcze_gram), 'gram']
            app.TUSZCZUEditField.Value = num2str(tluszcze_wynik)
            
            bialko_wynik = [num2str(bialko_kcal), 'kcal = ', num2str(bialko_gram), 'gram']
            app.BIAKAEditField.Value = num2str(bialko_wynik)
            
            wegle_wynik = [num2str(wegle_kcal), 'kcal = ', num2str(wegle_gram), 'gram']
            app.WGLOWODANWEditField.Value = num2str(wegle_wynik)
            
            global wegle_gram wegle_kcal bialko_gram bialko_kcal tluszcze_gram tluszcze_kcal 

        end

        % Button pushed function: PokawykresButton
        function PokawykresButtonPushed(app, event)
            figure
            wykres = app.UITable3.Data
            cz = wykres(1:size(wykres),1)
            dys = wykres(1:size(wykres),2)
            wykres.Properties.VariableNames = {'Dzien', 'Dystans'};
            app.UITable3.ColumnName = wykres.Properties.VariableNames;
            plot(wykres.Dzien, wykres.Dystans, '-o',"LineWidth",2)
            title('Aktywność fizyczna');
            ylabel('Kilometry');
            xlabel('Dzień');
            xticks(wykres.Dzien);
            
            figure
            wykres2 = app.UITable3_2.Data
            kcal = wykres2(1:size(wykres2),1)
            wykres2.Properties.VariableNames = {'Dzien', 'Kalorie'};
            app.UITable3_2.ColumnName = wykres2.Properties.VariableNames;
            plot(wykres2.Dzien, wykres2.Kalorie, '-o',"LineWidth",2,"Color",'red')
            title('Spalone kalorie');
            ylabel('kcal');
            xlabel('Dzień');
            xticks(wykres.Dzien);
        end

        % Button pushed function: ZapisztreningButton
        function ZapisztreningButtonPushed(app, event)
            dane_do_zapisu = app.UITable3.Data
            dane_do_zapisu2 = app.UITable3_2.Data
            writetable(dane_do_zapisu, 'trening_raport.xlsx', "WriteMode","inplace");
            writetable(dane_do_zapisu2, 'spalone_kalorie.xlsx', "WriteMode","inplace");
        end

        % Button pushed function: 
        % WywietlwarunkipogodowedlawybranejtrasyButton
        function WywietlwarunkipogodowedlawybranejtrasyButtonPushed(app, event)
            global nazwy_tras pobrane_trasy
            % !ALERT!
            %if app.TrasydowyboruListBox.Items == {}
            %    uialert(app.UIFigure, 'Proszę napoczątku wybrać trasę', ...
            %        'Błąd', "Icon", "error");
            %end            
            
            % WYSWIELTENIE OKIENEK NA POGODE
            app.indeks_uv.Visible = 'on';
            app.wiatr_predkosc.Visible = 'on';
            app.temperatura_celsjusz.Visible = 'on';
            app.cisnienie_hpa.Visible = 'on';
            
            app.IndeksUVLabel.Visible = 'on';
            app.WiatrLabel.Visible = 'on';
            app.TemperaturaLabel.Visible = 'on';
            app.CinienieLabel.Visible = 'on';
            
            
            % DANE
            wybrana_trasa = app.TrasydowyboruListBox.Value;
            wybrana_trasa = split(wybrana_trasa," -");
            wybrana_trasa = sprintf("%s.%s",wybrana_trasa{1},app.skrot);
            
            indeks_wybranej_trasy = find(nazwy_tras == wybrana_trasa);
            dane_wybranej_trasy = pobrane_trasy{indeks_wybranej_trasy};
            
            lat_pogoda = dane_wybranej_trasy.Latitude(1);
            lon_pogoda = dane_wybranej_trasy.Longitude(1);
            
            
            % POBRANIE DANYCH ZE STRONY
            key = '810cb7f0ed0a30ca1a5f3a68200384f3';
            options = weboptions('ContentType', 'auto');
            url = ['https://api.openweathermap.org/data/2.5/onecall?lat=', ...
                num2str(lat_pogoda), '&lon=', num2str(lon_pogoda), ...
                '&appid=', key, '&units=metric'];
            dane_pogodowe = webread(url, options);   


            % OPISY DLA WARUNKÓW POGODOWYCH
            wiatrw = dane_pogodowe.current.wind_speed;
            uviw = dane_pogodowe.current.uvi;
            tempw = dane_pogodowe.current.temp;    
            
            if wiatrw < 1
                wiatr_opis = ' (cisza)'
            elseif wiatrw <= 5
                wiatr_opis = ' (powiew)'
            elseif wiatrw <= 11
                wiatr_opis = ' (słaby wiatr)'
            elseif wiatrw <= 19
                wiatr_opis = ' (łagodny wiatr)'
            elseif wiatrw <= 28
                wiatr_opis = ' (umiarkowany wiatr)'
            elseif wiatrw <= 38
                wiatr_opis = ' (dość silny wiatr)'
            elseif wiatrw <= 49
                wiatr_opis = ' (silny wiatr)'
            elseif wiatrw <= 61
                wiatr_opis = ' (bardzo silny wiatr)'
            elseif wiatrw <= 74
                wiatr_opis = ' (sztorm)'
            else
                wiatr_opis = ' (NIE WYCHODŹ!)'
            end
            
            
            if uviw <= 2
                uvi_opis = ' (brak zagrożenia)'
            elseif uviw <= 5
                uvi_opis = ' (średnie zagrożenie podczas długiego pobytu na słońcu)'
            elseif uviw <= 7
                uvi_opis = ' (wysokie zagrożenie podczas długiego pobytu na słońcu)'
            elseif uviw <= 10
                uvi_opis = ' (bardzo wysokie zagrożenie!)'
            else
                uvi_opis = ' (EKSTREMALNE ZAGROŻENIE!)'            
            end
            
            
            if tempw >= 7 && tempw <= 15
                temp_opis = ' (optymalna)'
            else
                temp_opis = ''
            end
            
            
            temperatura = [num2str(tempw), ' C', char(176), temp_opis];
            cisnienie = [num2str(dane_pogodowe.current.pressure), ...
                ' hPa'];
            wiatr = [num2str(wiatrw), ' km/h', wiatr_opis];
            uvi = [num2str(uviw), uvi_opis];
            
            app.temperatura_celsjusz.Value = temperatura;
            app.cisnienie_hpa.Value = cisnienie;
            app.wiatr_predkosc.Value = wiatr;
            app.indeks_uv.Value = uvi;
            

        end

        % Button pushed function: UsutrasButton
        function UsutrasButtonPushed(app, event)
            [file,path,indx]=uigetfile('*.gpx','Wybierz plik do usunięcia.','trasy_po_woj');
            potwierdzenie = uiconfirm(app.UIFigure,'Czy chcesz usunąć ten plik?','Zatwierdź usunięcie',...
                        'Icon','warning','Options',{'Tak','Nie'});
            
            sciezka_dostepu = convertStringsToChars(sprintf("%s\%s",path,file));
            
            if potwierdzenie == 'Tak'
                delete(sciezka_dostepu)
                delete(sprintf("wszystkie_trasy\\%s",file))
                uialert(app.UIFigure,'Plik został usunięty poprawnie', 'Akceptacja',"Icon","success");
            end
        end

        % Button pushed function: PokarezultatButton
        function PokarezultatButtonPushed(app, event)
            trening_tabela = app.UITable3.Data;
            trening_tabela.Properties.VariableNames = {'Dzien', 'Dystans'};
            app.UITable3.ColumnName = trening_tabela.Properties.VariableNames;
            
            trening_tabela2 = app.UITable3_2.Data;
            trening_tabela2.Properties.VariableNames = {'Dzien', 'Kalorie'};
            app.UITable3_2.ColumnName = trening_tabela2.Properties.VariableNames;
            
            spalone_kalorie = app.spalone_kalorie.Value;
            dzien = app.DzieEditField.Value;
            dystans = app.trasa_w_dol.Value + app.trasa_w_gore.Value;
            for i = 1:(size(trening_tabela)-1)
                if dzien == trening_tabela.Dzien(i)
                    poprzedni_dystans = trening_tabela.Dystans(i);
                    poprzednie_kalorie = trening_tabela2.Kalorie(i);
                    trening_tabela.Dystans(i) = dystans + poprzedni_dystans;
                    trening_tabela2.Kalorie(i) = spalone_kalorie + poprzednie_kalorie;
                    app.UITable3.Data = trening_tabela;
                    app.UITable3_2.Data = trening_tabela2;
                end
            end
            if dzien ~= trening_tabela.Dzien
                zapis = {dzien dystans};
                zapis2 = {dzien spalone_kalorie};
                trening_tabela = [trening_tabela;zapis];
                trening_tabela2 = [trening_tabela2;zapis2]
                app.UITable3.Data = trening_tabela;
                app.UITable3_2.Data = trening_tabela2;
            end
        end

        % Button pushed function: WczytajpoprzednietreningiButton
        function WczytajpoprzednietreningiButtonPushed(app, event)
            try
            tabela2 = readtable('trening_raport.xlsx')
            tabela3 = readtable('spalone_kalorie.xlsx')
            
            %uialert(app.UIFigure, 'wczytano dane', 'OK', "Icon", 'success')
            catch
            uialert(app.UIFigure, 'Błąd', '!!!', "Icon", 'error')  
            end
            
            % wczytanie tabeli z danymi 
             app.UITable3.Enable = 'on';
             app.UITable3.Data = tabela2;
             app.UITable3.ColumnName = tabela2.Properties.VariableNames;
             
             app.UITable3_2.Enable = 'on';
             app.UITable3_2.Data = tabela3;
             app.UITable3_2.ColumnName = tabela3.Properties.VariableNames;
        end

        % Value changed function: ZapotrzebowaniebiakoEditField
        function ZapotrzebowaniebiakoEditFieldValueChanged(app, event)
            value = app.ZapotrzebowaniebiakoEditField.Value;
            
        end

        % Value changed function: ZapotrzebowaniewglowodanyEditField
        function ZapotrzebowaniewglowodanyEditFieldValueChanged(app, event)
            value = app.ZapotrzebowaniewglowodanyEditField.Value;
            
        end

        % Value changed function: ZapotrzebowanietuszczeEditField
        function ZapotrzebowanietuszczeEditFieldValueChanged(app, event)
            value = app.ZapotrzebowanietuszczeEditField.Value;
            
        end

        % Button pushed function: dodajproduktButton
        function dodajproduktButtonPushed(app, event)
              tabela = app.UITable4.Data
            prod = app.NazwaproduktuEditField.Value;
            kcal = app.KalorieEditField.Value;
            wegl = app.WeglowodanyEditField.Value;
            bial = app.BialkoEditField.Value;
            tlu = app.TluszczeEditField.Value;
                
            nowe = {prod kcal wegl bial tlu};
            tabela = [tabela;nowe];
            app.UITable4.Data = tabela;
            
            Kalorie = cell2mat(tabela(:,2))       
            Bialko = cell2mat(tabela(:,3))
            Tluszcze = cell2mat(tabela(:,4))
            Weglowodany = cell2mat(tabela(:,5))
            app.ZjedzonekalorieEditField.Value = num2str(sum(Kalorie))
            app.ZjedzonebiakoEditField.Value = num2str(sum(Bialko))
            app.ZjedzonetuszczeEditField.Value = num2str(sum(Tluszcze))
            app.ZjedzonewglowodanyEditField.Value = num2str(sum(Weglowodany))
        end

        % Button pushed function: wyczytabeleButton
        function wyczytabeleButtonPushed(app, event)
            tabela = app.UITable4.Data
            tabela = []
            app.UITable4.Data = tabela      
        end

        % Value changed function: NazwaproduktuEditField
        function NazwaproduktuEditFieldValueChanged(app, event)
            value = app.NazwaproduktuEditField.Value;
            
        end

        % Value changed function: KalorieEditField
        function KalorieEditFieldValueChanged(app, event)
            value = app.KalorieEditField.Value;
            
        end

        % Value changed function: WeglowodanyEditField
        function WeglowodanyEditFieldValueChanged(app, event)
            value = app.WeglowodanyEditField.Value;
            
        end

        % Value changed function: BialkoEditField
        function BialkoEditFieldValueChanged(app, event)
            value = app.BialkoEditField.Value;
            
        end

        % Value changed function: TluszczeEditField
        function TluszczeEditFieldValueChanged(app, event)
            value = app.TluszczeEditField.Value;
            
        end

        % Value changed function: ZjedzonekalorieEditField
        function ZjedzonekalorieEditFieldValueChanged(app, event)
            value = app.ZjedzonekalorieEditField.Value;
            
        end

        % Value changed function: ZjedzonebiakoEditField
        function ZjedzonebiakoEditFieldValueChanged(app, event)
            value = app.ZjedzonebiakoEditField.Value;
            
        end

        % Value changed function: ZjedzonetuszczeEditField
        function ZjedzonetuszczeEditFieldValueChanged(app, event)
            value = app.ZjedzonetuszczeEditField.Value;
            
        end

        % Value changed function: ZjedzonewglowodanyEditField
        function ZjedzonewglowodanyEditFieldValueChanged(app, event)
            value = app.ZjedzonewglowodanyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZdrowieTab = uitab(app.TabGroup);
            app.ZdrowieTab.Title = 'Zdrowie';
            app.TabGroup3 = uitabgroup(app.ZdrowieTab);
            app.MojedaneTab = uitab(app.TabGroup3);
            app.MojedaneTab.Title = 'Moje dane';
            app.PeButtonGroup = uibuttongroup(app.MojedaneTab);
            app.PeButtonGroup.Title = 'Płeć';
            app.MczyznaButton = uiradiobutton(app.PeButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.MczyznaButton.Value = true;
            app.KobietyButton = uiradiobutton(app.PeButtonGroup);
            app.KobietyButton.Text = 'Kobiety';
            app.AktywnoButtonGroup = uibuttongroup(app.MojedaneTab);
            app.AktywnoButtonGroup.Title = 'Aktywność';
            app.ZnikomaButton = uiradiobutton(app.AktywnoButtonGroup);
            app.ZnikomaButton.Text = 'Znikoma';
            app.ZnikomaButton.Value = true;
            app.MaaButton = uiradiobutton(app.AktywnoButtonGroup);
            app.MaaButton.Text = 'Mała';
            app.UmiarkowanaButton = uiradiobutton(app.AktywnoButtonGroup);
            app.UmiarkowanaButton.Text = 'Umiarkowana';
            app.DuaButton = uiradiobutton(app.AktywnoButtonGroup);
            app.DuaButton.Text = 'Duża';
            app.BardzoduaButton = uiradiobutton(app.AktywnoButtonGroup);
            app.BardzoduaButton.Text = 'Bardzo duża';
            app.PodajswjwiekEditFieldLabel = uilabel(app.MojedaneTab);
            app.PodajswjwiekEditFieldLabel.Text = 'Podaj swój wiek';
            app.PodajswjwiekEditField = uieditfield(app.MojedaneTab, 'numeric');
            app.PodajswjwiekEditField.ValueChangedFcn = createCallbackFcn(app, @PodajswjwiekEditFieldValueChanged, true);
            app.PodajswojwagkgLabel = uilabel(app.MojedaneTab);
            app.PodajswojwagkgLabel.Text = 'Podaj swoją wagę (kg)';
            app.PodajswojwagkgEditField = uieditfield(app.MojedaneTab, 'numeric');
            app.PodajswojwagkgEditField.ValueChangedFcn = createCallbackFcn(app, @PodajswojwagkgEditFieldValueChanged, true);
            app.PodajswjwzrostcmEditFieldLabel = uilabel(app.MojedaneTab);
            app.PodajswjwzrostcmEditFieldLabel.Text = 'Podaj swój wzrost (cm)';
            app.PodajswjwzrostcmEditField = uieditfield(app.MojedaneTab, 'numeric');
            app.PodajswjwzrostcmEditField.ValueChangedFcn = createCallbackFcn(app, @PodajswjwzrostcmEditFieldValueChanged, true);
            app.PodajswjobwdbiodercmEditFieldLabel = uilabel(app.MojedaneTab);
            app.PodajswjobwdbiodercmEditFieldLabel.Text = 'Podaj swój obwód bioder (cm)';
            app.PodajswjobwdbiodercmEditField = uieditfield(app.MojedaneTab, 'numeric');
            app.PodajswjobwdbiodercmEditField.ValueChangedFcn = createCallbackFcn(app, @PodajswjobwdbiodercmEditFieldValueChanged, true);
            app.PodajswjobwdwpasiecmEditFieldLabel = uilabel(app.MojedaneTab);
            app.PodajswjobwdwpasiecmEditFieldLabel.Text = 'Podaj swój obwód w pasie (cm)';
            app.PodajswjobwdwpasiecmEditField = uieditfield(app.MojedaneTab, 'numeric');
            app.PodajswjobwdwpasiecmEditField.ValueChangedFcn = createCallbackFcn(app, @PodajswjobwdwpasiecmEditFieldValueChanged, true);
            app.KalkulatorkaloriiTab = uitab(app.TabGroup3);
            app.KalkulatorkaloriiTab.Title = 'Kalkulator kalorii';
            app.TwojezapotrzebowaniekalorycznewynosiEditFieldLabel = uilabel(app.KalkulatorkaloriiTab);
            app.TwojezapotrzebowaniekalorycznewynosiEditFieldLabel.Text = 'Twoje zapotrzebowanie kaloryczne wynosi:';
            app.TwojezapotrzebowaniekalorycznewynosiEditField = uieditfield(app.KalkulatorkaloriiTab, 'text');
            app.TwojezapotrzebowaniekalorycznewynosiEditField.Value = '0';
            app.TwoimcelemjestabyButtonGroup = uibuttongroup(app.KalkulatorkaloriiTab);
            app.TwoimcelemjestabyButtonGroup.Title = 'Twoim celem jest aby:';
            app.UtrzymawagButton = uiradiobutton(app.TwoimcelemjestabyButtonGroup);
            app.UtrzymawagButton.Text = 'Utrzymać wagę';
            app.UtrzymawagButton.Value = true;
            app.SchudnButton = uiradiobutton(app.TwoimcelemjestabyButtonGroup);
            app.SchudnButton.Text = 'Schudnąć';
            app.PrzytyButton = uiradiobutton(app.TwoimcelemjestabyButtonGroup);
            app.PrzytyButton.Text = 'Przytyć';
            app.ObliczswojezapotrzebowaniekaloryczneButton = uibutton(app.KalkulatorkaloriiTab, 'push');
            app.ObliczswojezapotrzebowaniekaloryczneButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczswojezapotrzebowaniekaloryczneButtonPushed2, true);
            app.ObliczswojezapotrzebowaniekaloryczneButton.Text = 'Oblicz swoje zapotrzebowanie kaloryczne';
            app.RodzajaktywnocifizycznejButtonGroup = uibuttongroup(app.KalkulatorkaloriiTab);
            app.RodzajaktywnocifizycznejButtonGroup.Title = 'Rodzaj aktywności fizycznej: ';
            app.TrenujnasiowniuporawiamsportysioweButton = uiradiobutton(app.RodzajaktywnocifizycznejButtonGroup);
            app.TrenujnasiowniuporawiamsportysioweButton.Text = 'Trenuję na siłowni, uporawiam sporty siłowe';
            app.TrenujnasiowniuporawiamsportysioweButton.Value = true;
            app.TrenujsportywytrzymaociowenpbieganieButton = uiradiobutton(app.RodzajaktywnocifizycznejButtonGroup);
            app.TrenujsportywytrzymaociowenpbieganieButton.Text = 'Trenuję sporty wytrzymałościowe np. bieganie';
            app.TrenujodczasudoczasurnedyscyplinysportoweButton = uiradiobutton(app.RodzajaktywnocifizycznejButtonGroup);
            app.TrenujodczasudoczasurnedyscyplinysportoweButton.Text = {'Trenuję od czasu do czasu, różne '; 'dyscypliny sportowe'};
            app.TextArea_8 = uitextarea(app.KalkulatorkaloriiTab);
            app.TextArea_8.Value = {''; 'Rodzaj aktywności fizycznej, którą zazwyczaj uprawiamy,'; 'wpływa na ilość makroskładników, jakie powinniśmy spożywać.'; 'Dlatego np. ćwicząc siłowo, powinniśmy spożywać więcej białka, niż normalnie, '; 'a trenująć sporty wytrzymałościowe powinniśmy spożywać więcej węglowodanów.'};
            app.BIAKAEditFieldLabel = uilabel(app.KalkulatorkaloriiTab);
            app.BIAKAEditFieldLabel.Text = 'BIAŁKA';
            app.BIAKAEditField = uieditfield(app.KalkulatorkaloriiTab, 'text');
            app.PowinienespoywanastpujciloLabel = uilabel(app.KalkulatorkaloriiTab);
            app.PowinienespoywanastpujciloLabel.Text = 'Powinieneś spożywać następującą ilość:';
            app.WGLOWODANWEditFieldLabel = uilabel(app.KalkulatorkaloriiTab);
            app.WGLOWODANWEditFieldLabel.Text = 'WĘGLOWODANÓW';
            app.WGLOWODANWEditField = uieditfield(app.KalkulatorkaloriiTab, 'text');
            app.TUSZCZUEditFieldLabel = uilabel(app.KalkulatorkaloriiTab);
            app.TUSZCZUEditFieldLabel.Text = 'TŁUSZCZU';
            app.TUSZCZUEditField = uieditfield(app.KalkulatorkaloriiTab, 'text');
            app.TextArea_9 = uitextarea(app.KalkulatorkaloriiTab);
            app.TextArea_9.Value = {''; 'Aby prawidłowo obliczyć zapotrzebowanie kaloryczne, musimy określić sobie cel. Chcąc zdrowo schudnąć musimy przyjmować około 300 kcal mniej, niż przyjmowalibyśmy chcąc utrzymać wagę. Tak samo w drugą stronę- aby zdrowo przytyć powinniśmy jeść około 300 kcal więcej, niż zawyczaj '};
            app.ObliczzapotrzebowanienamakroskadnikiButton = uibutton(app.KalkulatorkaloriiTab, 'push');
            app.ObliczzapotrzebowanienamakroskadnikiButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczzapotrzebowanienamakroskadnikiButtonPushed, true);
            app.ObliczzapotrzebowanienamakroskadnikiButton.Text = 'Oblicz zapotrzebowanie na makroskładniki';
            app.KalkulatordietetycznyTab = uitab(app.TabGroup3);
            app.KalkulatordietetycznyTab.Title = 'Kalkulator dietetyczny';
            app.BMIEditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.ObliczWspczynnikiButton = uibutton(app.KalkulatordietetycznyTab, 'push');
            app.ObliczWspczynnikiButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczWspczynnikiButtonPushed, true);
            app.ObliczWspczynnikiButton.Text = 'Oblicz Współczynniki';
            app.WHREditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.WHREditFieldLabel.Text = 'WHR';
            app.WHREditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.OcenaLabel = uilabel(app.KalkulatordietetycznyTab);
            app.OcenaLabel.Text = 'Ocena';
            app.TextArea = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea.Value = {'Jest to jeden z najpopularniejszych kalkulatorów stosowanych przez dietetyków. BMI (ang. body mass index) to inaczej wskaźnik masy ciała, który dotyczy zależności między masą i wzrostem. Wzór na obliczenie BMI nie jest skomplikowany - masę ciała (kg) dzielimy przez kwadrat wysokości (m).'};
            app.TextArea_2 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_2.Value = {'Kalkulator WHR (Waist To Hip Ratio - stosunek obwodu talii do obwodu bioder) to jeden ze sposobów określania otyłości i nadwagi, a także narzędzie do opisu rozmieszczenia tkanki tłuszczowej w organizmie. Polega on na określaniu proporcji obwodu talii do obwodu bioder, przy czym wskaźnik ten przybiera różne wartości u kobiet i mężczyzn. Dla kobiet powinien on wynosić ok.0.8 a u mężczyzn ok.1.'};
            app.WHtREditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.WHtREditFieldLabel.Text = 'WHtR';
            app.WHtREditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.TextArea_3 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_3.Value = {'Kalkulator WHTR ( ang. waist to height ratio) - wskaźnik talia wzrost, to stosunek obwodu talii do wzrostu. Jest to wskaźnik oceniający stan odżywienia, zaraz obok BMI oraz WHR. Pomiar ten bierze pod uwagę okolice brzucha (liczony obwód talii), czyli miejsce, w którym gromadzi się najwięcej tkanki tłuszczowej.'};
            app.OcenaLabel_2 = uilabel(app.KalkulatordietetycznyTab);
            app.OcenaLabel_2.Text = 'Ocena';
            app.WzrBrocaEditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.WzrBrocaEditFieldLabel.Text = 'Wzór Broca';
            app.WzrBrocaEditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.TextArea_4 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_4.Value = {'Spośród wszystkich wzorów do ustalania prawidłowej masy ciała, najprostszym jest wzór Broca. Broc założył, że idealna waga człowieka to wzrost w cm odjąć 100. Wzór ten został na przestrzeni lat udoskonalony przez dietetyków, w wyniku czego w przypadku młodych kobiet obliczoną różnicę zmiejszamy o 10%, a w przypadku młodych mężczyzn o 5%.'};
            app.WzrBrocaBrugshaEditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.WzrBrocaBrugshaEditFieldLabel.Text = 'Wzór Broca-Brugsha ';
            app.WzrBrocaBrugshaEditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.TextArea_5 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_5.Value = {'Wskaźnik Broca-Brugsha jest podobny do podstawowego wzoru Broca. Wartość odejmowana od wzrostu w celu ustalenia wagi jest zmienna w zależności od przedziału wzrostu, w jakim znajduje się dana osoba.'};
            app.Label_2 = uilabel(app.KalkulatordietetycznyTab);
            app.Label_2.Text = '';
            app.PPMwzrMillfinaEditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.PPMwzrMillfinaEditFieldLabel.Text = 'PPM- wzór Millfina';
            app.PPMwzrMillfinaEditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.TextArea_6 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_6.Value = {'Kalkulator podstawowej przemiany materii według wzoru Mifflina.'; 'Wzór Mifflina służy do obliczenia podstawowej przemiany materii - PPM. Podstawową przemianą materii ( w skrócie PPM lub BMR z ang. basal metabolic rate) nazywamy najniższy poziom zapotrzebowania energetycznego umożliwiający zachowanie podstawowych funckji w optymalnych warunkach bytowych.'};
            app.YMCAEditFieldLabel = uilabel(app.KalkulatordietetycznyTab);
            app.YMCAEditFieldLabel.Text = 'YMCA';
            app.YMCAEditField = uieditfield(app.KalkulatordietetycznyTab, 'numeric');
            app.TextArea_7 = uitextarea(app.KalkulatordietetycznyTab);
            app.TextArea_7.Value = {'Kalkulator YMCA jest kalkulatorem obliczającym poziom tkanki tłuszczowej w organizmie człowieka.'; 'Znajomość procentowej zawartości tkanki tłuszczowej jest kluczowa podczas układania diety. Wiadomo wtedy czy dieta ma być nisko czy wysokotłuszczowa. YMCA stanowi idealne narzędzie do określania i śledzenia na bieżąco postępów w odchudzaniu.'};
            app.WartodywczeTab = uitab(app.TabGroup);
            app.WartodywczeTab.Title = 'Wart odżywcze';
            app.TabGroup2 = uitabgroup(app.WartodywczeTab);
            app.WizualizacjaTab = uitab(app.TabGroup2);
            app.WizualizacjaTab.Title = 'Wizualizacja';
            app.ZapotrzebowaniekalorieEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZapotrzebowaniekalorieEditFieldLabel.Text = 'Zapotrzebowanie kalorie';
            app.ZapotrzebowaniekalorieEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZapotrzebowaniekalorieEditField.ValueChangedFcn = createCallbackFcn(app, @ZapotrzebowaniekalorieEditFieldValueChanged, true);
            app.ZapotrzebowaniekalorieEditField.ValueChangingFcn = createCallbackFcn(app, @ZapotrzebowaniekalorieEditFieldValueChanging, true);
            app.ZjedzonekalorieEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZjedzonekalorieEditFieldLabel.Text = 'Zjedzone kalorie';
            app.ZjedzonekalorieEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZjedzonekalorieEditField.ValueChangedFcn = createCallbackFcn(app, @ZjedzonekalorieEditFieldValueChanged, true);
            app.ZapotrzebowaniebiakoEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZapotrzebowaniebiakoEditFieldLabel.Text = 'Zapotrzebowanie białko';
            app.ZapotrzebowaniebiakoEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZapotrzebowaniebiakoEditField.ValueChangedFcn = createCallbackFcn(app, @ZapotrzebowaniebiakoEditFieldValueChanged, true);
            app.ZjedzonebiakoEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZjedzonebiakoEditFieldLabel.Text = 'Zjedzone białko';
            app.ZjedzonebiakoEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZjedzonebiakoEditField.ValueChangedFcn = createCallbackFcn(app, @ZjedzonebiakoEditFieldValueChanged, true);
            app.ZapotrzebowanietuszczeEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZapotrzebowanietuszczeEditFieldLabel.Text = 'Zapotrzebowanie tłuszcze';
            app.ZapotrzebowanietuszczeEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZapotrzebowanietuszczeEditField.ValueChangedFcn = createCallbackFcn(app, @ZapotrzebowanietuszczeEditFieldValueChanged, true);
            app.ZjedzonetuszczeEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZjedzonetuszczeEditFieldLabel.Text = 'Zjedzone tłuszcze';
            app.ZjedzonetuszczeEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZjedzonetuszczeEditField.ValueChangedFcn = createCallbackFcn(app, @ZjedzonetuszczeEditFieldValueChanged, true);
            app.ZapotrzebowaniewglowodanyEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZapotrzebowaniewglowodanyEditFieldLabel.Text = 'Zapotrzebowanie węglowodany';
            app.ZapotrzebowaniewglowodanyEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZapotrzebowaniewglowodanyEditField.ValueChangedFcn = createCallbackFcn(app, @ZapotrzebowaniewglowodanyEditFieldValueChanged, true);
            app.ZjedzonewglowodanyEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.ZjedzonewglowodanyEditFieldLabel.Text = 'Zjedzone węglowodany';
            app.ZjedzonewglowodanyEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.ZjedzonewglowodanyEditField.ValueChangedFcn = createCallbackFcn(app, @ZjedzonewglowodanyEditFieldValueChanged, true);
            app.UITable4 = uitable(app.WizualizacjaTab);
            app.UITable4.ColumnName = {'Produkt'; 'Kalorie'; 'Weglowodany'; 'Bialko'; 'Tluszcze'};
            app.UITable4.RowName = {};
            app.dodajproduktButton = uibutton(app.WizualizacjaTab, 'push');
            app.dodajproduktButton.ButtonPushedFcn = createCallbackFcn(app, @dodajproduktButtonPushed, true);
            app.dodajproduktButton.Text = 'dodaj produkt';
            app.wyczytabeleButton = uibutton(app.WizualizacjaTab, 'push');
            app.wyczytabeleButton.ButtonPushedFcn = createCallbackFcn(app, @wyczytabeleButtonPushed, true);
            app.wyczytabeleButton.Text = 'wyczyść tabele';
            app.NazwaproduktuEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.NazwaproduktuEditFieldLabel.Text = 'Nazwa produktu';
            app.NazwaproduktuEditField = uieditfield(app.WizualizacjaTab, 'text');
            app.NazwaproduktuEditField.ValueChangedFcn = createCallbackFcn(app, @NazwaproduktuEditFieldValueChanged, true);
            app.KalorieEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.KalorieEditFieldLabel.Text = 'Kalorie';
            app.KalorieEditField = uieditfield(app.WizualizacjaTab, 'numeric');
            app.KalorieEditField.ValueChangedFcn = createCallbackFcn(app, @KalorieEditFieldValueChanged, true);
            app.WeglowodanyEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.WeglowodanyEditFieldLabel.Text = 'Weglowodany';
            app.WeglowodanyEditField = uieditfield(app.WizualizacjaTab, 'numeric');
            app.WeglowodanyEditField.ValueChangedFcn = createCallbackFcn(app, @WeglowodanyEditFieldValueChanged, true);
            app.BialkoEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.BialkoEditFieldLabel.Text = 'Bialko';
            app.BialkoEditField = uieditfield(app.WizualizacjaTab, 'numeric');
            app.BialkoEditField.ValueChangedFcn = createCallbackFcn(app, @BialkoEditFieldValueChanged, true);
            app.TluszczeEditFieldLabel = uilabel(app.WizualizacjaTab);
            app.TluszczeEditFieldLabel.Text = 'Tluszcze';
            app.TluszczeEditField = uieditfield(app.WizualizacjaTab, 'numeric');
            app.TluszczeEditField.ValueChangedFcn = createCallbackFcn(app, @TluszczeEditFieldValueChanged, true);
            app.EdytujbazdanychTab = uitab(app.TabGroup2);
            app.EdytujbazdanychTab.Title = 'Edytuj bazę danych';
            app.WczytajbazdanychButton = uibutton(app.EdytujbazdanychTab, 'push');
            app.WczytajbazdanychButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajbazdanychButtonPushed, true);
            app.WczytajbazdanychButton.Text = {'Wczytaj bazę danych '; ''};
            app.UITable = uitable(app.EdytujbazdanychTab);
            app.UITable.ColumnName = {'Produkt'; 'Kalorie'; 'Weglowodany'; 'Bialko'; 'Tluszcz'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.EdytujbazdanychTab);
            app.DodajproduktdobazydanychButton = uibutton(app.EdytujbazdanychTab, 'push');
            app.DodajproduktdobazydanychButton.ButtonPushedFcn = createCallbackFcn(app, @DodajproduktdobazydanychButtonPushed, true);
            app.DodajproduktdobazydanychButton.Text = 'Dodaj produkt do bazy danych ';
            app.EdytujproduktButton = uibutton(app.EdytujbazdanychTab, 'push');
            app.EdytujproduktButton.ButtonPushedFcn = createCallbackFcn(app, @EdytujproduktButtonPushed, true);
            app.EdytujproduktButton.Text = 'Edytuj produkt';
            app.UsuproduktButton = uibutton(app.EdytujbazdanychTab, 'push');
            app.UsuproduktButton.ButtonPushedFcn = createCallbackFcn(app, @UsuproduktButtonPushed, true);
            app.UsuproduktButton.Text = 'Usuń produkt ';
            app.WpiszNazweProduktuZWielkiejLiteryEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.WpiszNazweProduktuZWielkiejLiteryEditFieldLabel.Text = 'WpiszNazweProduktuZWielkiejLitery';
            app.WpiszNazweProduktuZWielkiejLiteryEditField = uieditfield(app.EdytujbazdanychTab, 'text');
            app.WpiszNazweProduktuZWielkiejLiteryEditField.ValueChangedFcn = createCallbackFcn(app, @WpiszNazweProduktuZWielkiejLiteryEditFieldValueChanged, true);
            app.NazwaNoweEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.NazwaNoweEditFieldLabel.Text = 'NazwaNowe';
            app.NazwaNoweEditField = uieditfield(app.EdytujbazdanychTab, 'text');
            app.KalorieNoweEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.KalorieNoweEditFieldLabel.Text = 'KalorieNowe';
            app.KalorieNoweEditField = uieditfield(app.EdytujbazdanychTab, 'numeric');
            app.WeglNowegEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.WeglNowegEditFieldLabel.Text = 'WeglNowe [g]';
            app.WeglNowegEditField = uieditfield(app.EdytujbazdanychTab, 'numeric');
            app.BialkoNowegEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.BialkoNowegEditFieldLabel.Text = 'BialkoNowe [g]';
            app.BialkoNowegEditField = uieditfield(app.EdytujbazdanychTab, 'numeric');
            app.TluszczeNowegEditFieldLabel = uilabel(app.EdytujbazdanychTab);
            app.TluszczeNowegEditFieldLabel.Text = 'TluszczeNowe [g]';
            app.TluszczeNowegEditField = uieditfield(app.EdytujbazdanychTab, 'numeric');
            app.ZapiszzmianyButton = uibutton(app.EdytujbazdanychTab, 'push');
            app.ZapiszzmianyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszzmianyButtonPushed, true);
            app.ZapiszzmianyButton.Text = 'Zapisz zmiany ';
            app.SportTab = uitab(app.TabGroup);
            app.SportTab.Title = 'Sport';
            app.TabGroup4 = uitabgroup(app.SportTab);
            app.TrasaTab = uitab(app.TabGroup4);
            app.TrasaTab.Title = 'Trasa';
            app.RodzajaktywnociButtonGroup = uibuttongroup(app.TrasaTab);
            app.RodzajaktywnociButtonGroup.Title = 'Rodzaj aktywności:';
            app.BieganieButton = uiradiobutton(app.RodzajaktywnociButtonGroup);
            app.BieganieButton.Text = 'Bieganie';
            app.BieganieButton.Value = true;
            app.RowerButton = uiradiobutton(app.RodzajaktywnociButtonGroup);
            app.RowerButton.Text = 'Rower';
            app.WybierzwojewdztwoButtonGroup = uibuttongroup(app.TrasaTab);
            app.WybierzwojewdztwoButtonGroup.Title = 'Wybierz województwo:';
            app.dolnolskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.dolnolskieButton.Text = 'dolnośląskie';
            app.dolnolskieButton.Value = true;
            app.kujawskopomorskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.kujawskopomorskieButton.Text = 'kujawsko-pomorskie';
            app.lubelskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.lubelskieButton.Text = 'lubelskie';
            app.lubuskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.lubuskieButton.Text = 'lubuskie';
            app.dzkieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.dzkieButton.Text = 'łódzkie';
            app.maopolskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.maopolskieButton.Text = 'małopolskie';
            app.mazowieckieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.mazowieckieButton.Text = 'mazowieckie';
            app.opolskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.opolskieButton.Text = 'opolskie';
            app.podkarpackieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.podkarpackieButton.Text = 'podkarpackie';
            app.podlaskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.podlaskieButton.Text = 'podlaskie';
            app.pomorskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.pomorskieButton.Text = 'pomorskie';
            app.lskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.lskieButton.Text = 'śląskie';
            app.witokrzyskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.witokrzyskieButton.Text = 'świętokrzyskie';
            app.warmiskomazurskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.warmiskomazurskieButton.Text = 'warmińsko-mazurskie';
            app.wielkopolskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.wielkopolskieButton.Text = 'wielkopolskie';
            app.zachodniopomorskieButton = uiradiobutton(app.WybierzwojewdztwoButtonGroup);
            app.zachodniopomorskieButton.Text = 'zachodniopomorskie';
            app.DugotrasywkmLabel = uilabel(app.TrasaTab);
            app.DugotrasywkmLabel.Text = 'Długość trasy w [km]:';
            app.SzukajButton = uibutton(app.TrasaTab, 'push');
            app.SzukajButton.ButtonPushedFcn = createCallbackFcn(app, @SzukajButtonPushed, true);
            app.SzukajButton.Text = 'Szukaj';
            app.PokatrasButton = uibutton(app.TrasaTab, 'state');
            app.PokatrasButton.ValueChangedFcn = createCallbackFcn(app, @PokatrasButtonValueChanged, true);
            app.PokatrasButton.Text = 'Pokaż trasę';
            app.PokaprofilterenuButton = uibutton(app.TrasaTab, 'state');
            app.PokaprofilterenuButton.ValueChangedFcn = createCallbackFcn(app, @PokaprofilterenuButtonValueChanged, true);
            app.PokaprofilterenuButton.Text = 'Pokaż profil terenu';
            app.DodanienowejtrasydobazydanychLabel = uilabel(app.TrasaTab);
            app.DodanienowejtrasydobazydanychLabel.Text = 'Dodanie nowej trasy do bazy danych:';
            app.DodajtrasButton = uibutton(app.TrasaTab, 'push');
            app.DodajtrasButton.ButtonPushedFcn = createCallbackFcn(app, @DodajtrasButtonPushed, true);
            app.DodajtrasButton.Text = 'Dodaj trasę';
            app.MAXEditFieldLabel = uilabel(app.TrasaTab);
            app.MAXEditFieldLabel.Text = ' MAX:';
            app.MAXEditField = uieditfield(app.TrasaTab, 'numeric');
            app.MINEditFieldLabel = uilabel(app.TrasaTab);
            app.MINEditFieldLabel.Text = 'MIN:';
            app.MINEditField = uieditfield(app.TrasaTab, 'numeric');
            app.TrasydowyboruListBoxLabel = uilabel(app.TrasaTab);
            app.TrasydowyboruListBoxLabel.Text = {'Trasy do wyboru:'; ''};
            app.TrasydowyboruListBox = uilistbox(app.TrasaTab);
            app.TrasydowyboruListBox.Items = {'', ''};
            app.TrasydowyboruListBox.Value = '';
            app.WybierzwojewdztwodlanowejtrasyDropDownLabel = uilabel(app.TrasaTab);
            app.WybierzwojewdztwodlanowejtrasyDropDownLabel.Text = {'Wybierz województwo'; 'dla nowej trasy:'; ''};
            app.WybierzwojewdztwodlanowejtrasyDropDown = uidropdown(app.TrasaTab);
            app.WybierzwojewdztwodlanowejtrasyDropDown.Items = {'dolnośląskie', 'kujawsko-pomorskie', 'lubelskie', 'lubuskie', 'łódzkie', 'małopolskie', 'mazowieckie', 'opolskie', 'podkarpackie', 'podlaskie', 'pomorskie', 'śląskie', 'świętokrzyskie', 'warmińsko-mazurskie', 'wielkopolskie', 'zachodniopomorskie'};
            app.WybierzwojewdztwodlanowejtrasyDropDown.Value = 'dolnośląskie';
            app.WybierzrodzajaktywnocidlanowejtrasyDropDownLabel = uilabel(app.TrasaTab);
            app.WybierzrodzajaktywnocidlanowejtrasyDropDownLabel.Text = {'Wybierz rodzaj aktywności'; 'dla nowej trasy:'};
            app.WybierzrodzajaktywnocidlanowejtrasyDropDown = uidropdown(app.TrasaTab);
            app.WybierzrodzajaktywnocidlanowejtrasyDropDown.Items = {'Bieganie', 'Rower'};
            app.WybierzrodzajaktywnocidlanowejtrasyDropDown.Value = 'Bieganie';
            app.TemperaturaLabel = uilabel(app.TrasaTab);
            app.TemperaturaLabel.Visible = 'off';
            app.TemperaturaLabel.Text = 'Temperatura:';
            app.CinienieLabel = uilabel(app.TrasaTab);
            app.CinienieLabel.Visible = 'off';
            app.CinienieLabel.Text = 'Ciśnienie:';
            app.IndeksUVLabel = uilabel(app.TrasaTab);
            app.IndeksUVLabel.Visible = 'off';
            app.IndeksUVLabel.Text = 'Indeks UV:';
            app.WiatrLabel = uilabel(app.TrasaTab);
            app.WiatrLabel.Visible = 'off';
            app.WiatrLabel.Text = 'Wiatr:';
            app.WywietlwarunkipogodowedlawybranejtrasyButton = uibutton(app.TrasaTab, 'push');
            app.WywietlwarunkipogodowedlawybranejtrasyButton.ButtonPushedFcn = createCallbackFcn(app, @WywietlwarunkipogodowedlawybranejtrasyButtonPushed, true);
            app.WywietlwarunkipogodowedlawybranejtrasyButton.Text = 'Wyświetl warunki pogodowe dla wybranej trasy';
            app.cisnienie_hpa = uieditfield(app.TrasaTab, 'text');
            app.cisnienie_hpa.Visible = 'off';
            app.temperatura_celsjusz = uieditfield(app.TrasaTab, 'text');
            app.temperatura_celsjusz.Visible = 'off';
            app.wiatr_predkosc = uieditfield(app.TrasaTab, 'text');
            app.wiatr_predkosc.Visible = 'off';
            app.indeks_uv = uieditfield(app.TrasaTab, 'text');
            app.indeks_uv.Visible = 'off';
            app.UsutrasButton = uibutton(app.TrasaTab, 'push');
            app.UsutrasButton.ButtonPushedFcn = createCallbackFcn(app, @UsutrasButtonPushed, true);
            app.UsutrasButton.Text = 'Usuń trasę';
            app.StatystykiTab = uitab(app.TabGroup4);
            app.StatystykiTab.Title = 'Statystyki';
            app.SpalonekalorieLabel = uilabel(app.StatystykiTab);
            app.SpalonekalorieLabel.Text = 'Spalone kalorie:';
            app.spalone_kalorie = uieditfield(app.StatystykiTab, 'numeric');
            app.redniaprdkokmhLabel = uilabel(app.StatystykiTab);
            app.redniaprdkokmhLabel.Text = 'Średnia prędkość [km/h]:';
            app.rednietempominkmLabel = uilabel(app.StatystykiTab);
            app.rednietempominkmLabel.Text = 'Średnie tempo [min/km]:';
            app.srednia_predkosc = uieditfield(app.StatystykiTab, 'numeric');
            app.srednie_tempo = uieditfield(app.StatystykiTab, 'numeric');
            app.CzaswiczeminLabel = uilabel(app.StatystykiTab);
            app.CzaswiczeminLabel.Text = 'Czas ćwiczeń [min]:';
            app.czas_cwiczen = uieditfield(app.StatystykiTab, 'numeric');
            app.SumarycznadugotrasypodgrkmLabel = uilabel(app.StatystykiTab);
            app.SumarycznadugotrasypodgrkmLabel.Text = 'Sumaryczna długość trasy pod górę [km]:';
            app.trasa_w_gore = uieditfield(app.StatystykiTab, 'numeric');
            app.trasa_w_dol = uieditfield(app.StatystykiTab, 'numeric');
            app.SumarycznadugotrasywdkmLabel = uilabel(app.StatystykiTab);
            app.SumarycznadugotrasywdkmLabel.Text = 'Sumaryczna długość trasy w dół [km]:';
            app.ObliczstatystkinawybranejtrasieButton = uibutton(app.StatystykiTab, 'push');
            app.ObliczstatystkinawybranejtrasieButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczstatystkinawybranejtrasieButtonPushed, true);
            app.ObliczstatystkinawybranejtrasieButton.Text = 'Oblicz statystki na wybranej trasie';
            app.Label_3 = uilabel(app.StatystykiTab);
            app.Label_3.Text = '__________________________________________________________________________________________________________________________________________';
            app.PokawykresButton = uibutton(app.StatystykiTab, 'push');
            app.PokawykresButton.ButtonPushedFcn = createCallbackFcn(app, @PokawykresButtonPushed, true);
            app.PokawykresButton.Text = 'Pokaż wykres';
            app.UITable3 = uitable(app.StatystykiTab);
            app.UITable3.ColumnName = {'Dzień'; 'Dystans'};
            app.UITable3.RowName = {};
            app.ZapisztreningButton = uibutton(app.StatystykiTab, 'push');
            app.ZapisztreningButton.ButtonPushedFcn = createCallbackFcn(app, @ZapisztreningButtonPushed, true);
            app.ZapisztreningButton.Text = 'Zapisz trening';
            app.UITable3_2 = uitable(app.StatystykiTab);
            app.UITable3_2.ColumnName = {'Dzień'; 'Kalorie'};
            app.UITable3_2.RowName = {};
            app.PokarezultatButton = uibutton(app.StatystykiTab, 'push');
            app.PokarezultatButton.ButtonPushedFcn = createCallbackFcn(app, @PokarezultatButtonPushed, true);
            app.PokarezultatButton.Text = 'Pokaż rezultat';
            app.WczytajpoprzednietreningiButton = uibutton(app.StatystykiTab, 'push');
            app.WczytajpoprzednietreningiButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajpoprzednietreningiButtonPushed, true);
            app.WczytajpoprzednietreningiButton.Text = 'Wczytaj poprzednie treningi';
            app.TextArea_10 = uitextarea(app.StatystykiTab);
            app.TextArea_10.Value = {'Aby wygenerować raport ze swojej aktywności postępuj według następujących kroków:'; '1) Wpisz dzień treningu '; '2) Kliknij przycisk "Wczytaj poprzednie treningi" '; '3) Kliknij przycisk "Pokaż rezultaty"'; '4) Aby zapisać trening kliknij "Zapisz trening"'; '5) Aby zobaczyć swój postęp kliknij "Pokaż wykres"'};
            app.DzieEditFieldLabel = uilabel(app.StatystykiTab);
            app.DzieEditFieldLabel.Text = 'Dzień';
            app.DzieEditField = uieditfield(app.StatystykiTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end